classdef AframeCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ParmetrosdimensionalesdeloscomponentesTab  matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        NmerodetubosEditField           matlab.ui.control.NumericEditField
        NmerodetubosEditFieldLabel      matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        DimetroEditField                matlab.ui.control.NumericEditField
        DimetroEditFieldLabel           matlab.ui.control.Label
        MostrardatosButton              matlab.ui.control.Button
        ParmetrosdelventiladorLabel     matlab.ui.control.Label
        LongituddetubosEditField        matlab.ui.control.NumericEditField
        LongituddetubosEditFieldLabel   matlab.ui.control.Label
        AnchuradetuboEditField          matlab.ui.control.NumericEditField
        AnchuradetuboEditFieldLabel     matlab.ui.control.Label
        ProfundidaddetuboEditField      matlab.ui.control.NumericEditField
        ProfundidaddetuboLabel          matlab.ui.control.Label
        ParmetrosdelostubosLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        CondicionesdeoperacinTab        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        DiseoeficienteButton            matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        DBLabel                         matlab.ui.control.Label
        RuidoEditField                  matlab.ui.control.NumericEditField
        RuidoEditFieldLabel             matlab.ui.control.Label
        ModificarceldasButton           matlab.ui.control.Button
        KLabel_5                        matlab.ui.control.Label
        TemperaturaaguacondensadaEditField  matlab.ui.control.NumericEditField
        TemperaturaaguacondensadaEditFieldLabel  matlab.ui.control.Label
        PotenciadecondensacinEditField  matlab.ui.control.NumericEditField
        PotenciadecondensacinEditFieldLabel  matlab.ui.control.Label
        rpmLabel                        matlab.ui.control.Label
        KLabel_4                        matlab.ui.control.Label
        VelocidaddelventiladorEditField  matlab.ui.control.NumericEditField
        NmerodeceldasEditField          matlab.ui.control.NumericEditField
        TemperaturadelairealasalidaEditField  matlab.ui.control.NumericEditField
        CalcularButton                  matlab.ui.control.Button
        barLabel                        matlab.ui.control.Label
        VelocidaddelventiladorEditFieldLabel  matlab.ui.control.Label
        NmerodeceldasEditFieldLabel     matlab.ui.control.Label
        TemperaturadelairealasalidaEditFieldLabel  matlab.ui.control.Label
        KLabel_3                        matlab.ui.control.Label
        KLabel_2                        matlab.ui.control.Label
        MWLabel                         matlab.ui.control.Label
        PresinatmosfricaEditField       matlab.ui.control.NumericEditField
        PresinatmosfricaEditFieldLabel  matlab.ui.control.Label
        TemperaturaambienteEditField    matlab.ui.control.NumericEditField
        TemperaturadecondensacinEditField  matlab.ui.control.NumericEditField
        TemperaturaambienteEditFieldLabel  matlab.ui.control.Label
        TemperaturadecondensacinEditFieldLabel  matlab.ui.control.Label
        IntroduzcalossiguientesparmetrosLabel  matlab.ui.control.Label
        ConsumosTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        PuntodefuncionamientoLabel      matlab.ui.control.Label
        KWLabel                         matlab.ui.control.Label
        RendiminetodelventiladorEditField  matlab.ui.control.NumericEditField
        RendiminetodelventiladorEditFieldLabel  matlab.ui.control.Label
        ConsumodelosventiladoresEditField  matlab.ui.control.NumericEditField
        ConsumodelosventiladoresEditFieldLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MostrardatosButton
        function MostrardatosButtonPushed(app, event)
            run('CalcularNtubosD.mlx');
            app.ProfundidaddetuboEditField.Value=Ht;
            app.AnchuradetuboEditField.Value=Wt;
            app.LongituddetubosEditField.Value=Ltubos;
            app.NmerodetubosEditField.Value=Nt;
            app.DimetroEditField.Value=Dv;
            x=linspace(-2000,2000);
       y = 320.0451719 - 0.2975215484 .* x + 6.351486e-4 .* x.^2 - 8.14e-7 .* x.^3;
       y2= 186645.2333 - 59.413863388 .* x + 0.476168398 .* x.^2 - 5.08308e-4 .* x.^3;
       plot(app.UIAxes, x, y, 'b-', 'LineWidth', 2);  % azul
       hold(app.UIAxes, 'on');
       hold(app.UIAxes, 'off');
       plot(app.UIAxes3, x, y2, 'r-', 'LineWidth', 2); % rojo punteado
       hold(app.UIAxes3, 'on');
       hold(app.UIAxes3, 'off');
       
        end

        % Value changed function: ProfundidaddetuboEditField
        function ProfundidaddetuboEditFieldValueChanged(app, event)
            Ht = app.ProfundidaddetuboEditField.Value;
            
        end

        % Value changed function: AnchuradetuboEditField
        function AnchuradetuboEditFieldValueChanged(app, event)
            Wt = app.AnchuradetuboEditField.Value;
            
        end

        % Value changed function: LongituddetubosEditField
        function LongituddetubosEditFieldValueChanged(app, event)
            Ltubos = app.LongituddetubosEditField.Value;
            
        end

        % Value changed function: NmerodetubosEditField
        function NmerodetubosEditFieldValueChanged(app, event)
            Nt = app.NmerodetubosEditField.Value;
            
        end

        % Value changed function: DimetroEditField
        function DimetroEditFieldValueChanged(app, event)
            Dv = app.DimetroEditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
       y = 320.0451719 - 0.2975215484 * x + 6.351486e-4 * x^2 - 8.14e-7 * x^3;
       
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
         y2=186645.2333 - 59.413863388 * x + 0.476168398 * x.^2 - 5.08308e-4 * x.^3;   
        end

        % Value changed function: TemperaturaambienteEditField
        function TemperaturaambienteEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: TemperaturadecondensacinEditField
        function TemperaturadecondensacinEditFieldValueChanged(app, event)
            
        end

        % Value changed function: PresinatmosfricaEditField
        function PresinatmosfricaEditFieldValueChanged(app, event)
            
        end

        % Value changed function: TemperaturadelairealasalidaEditField
        function TemperaturadelairealasalidaEditFieldValueChanged(app, event)
            app.TemperaturadelairealasalidaEditField.Value=Tao;
            
        end

        % Value changed function: NmerodeceldasEditField
        function NmerodeceldasEditFieldValueChanged(app, event)
            app.NmerodeceldasEditField.Value=Naero;
            
        end

        % Value changed function: VelocidaddelventiladorEditField
        function VelocidaddelventiladorEditFieldValueChanged(app, event)
            app.VelocidaddelventiladorEditField.Value=N;
            
        end

        % Value changed function: TemperaturaaguacondensadaEditField
        function TemperaturaaguacondensadaEditFieldValueChanged(app, event)
            app.TemperaturaaguacondensadaEditField.Value=Tvo;
            
        end

        % Value changed function: PotenciadecondensacinEditField
        function PotenciadecondensacinEditFieldValueChanged2(app, event)
            
        end

        % Value changed function: ConsumodelosventiladoresEditField
        function ConsumodelosventiladoresEditFieldValueChanged(app, event)
            app.ConsumodelosventiladoresEditField.Value=Ptotal;
            
        end

        % Value changed function: RendiminetodelventiladorEditField
        function RendiminetodelventiladorEditFieldValueChanged(app, event)
            app.RendiminetodelventiladorEditField.Value=Rendimiento;
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            y3 = 320.0451719 - 0.2975215484 .* x + 6.351486e-4 .* x.^2 - 8.14e-7 .* x.^3;
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            y4=186645.2333 - 59.413863388 * x + 0.476168398 * x.^2 - 5.08308e-4 * x.^3;
        end

        % Value changed function: RuidoEditField
        function RuidoEditFieldValueChanged(app, event)
            app.RuidoEditField.Value=L;
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed2(app, event)
            Qu=app.PotenciadecondensacinEditField.Value;
            Tamb=app.TemperaturaambienteEditField.Value;
            Tcond=app.TemperaturadecondensacinEditField.Value;
            Pu=app.PresinatmosfricaEditField.Value; 
            run('CalcularNaerosD.mlx');
            run('CalcularTaoD.mlx');
            run('CalcularVelocidadD.mlx');
if N>=500

            app.NmerodeceldasEditField.Value=0;
            app.TemperaturadelairealasalidaEditField.Value=0;
            app.VelocidaddelventiladorEditField.Value=0;
            app.TemperaturaaguacondensadaEditField.Value=0;
            app.RuidoEditField.Value=0;
            app.ConsumodelosventiladoresEditField.Value=0;
            app.RendiminetodelventiladorEditField.Value=0;
else
            app.NmerodeceldasEditField.Value=Naero;
            app.TemperaturadelairealasalidaEditField.Value=Tao;
            app.VelocidaddelventiladorEditField.Value=N;
            app.TemperaturaaguacondensadaEditField.Value=Tvo;
            app.RuidoEditField.Value=L;
end
            run("CalcularPotenciaD.mlx");
            app.ConsumodelosventiladoresEditField.Value=Ptotal;
            app.RendiminetodelventiladorEditField.Value=Rendimiento;
       x2=linspace(-2000,2000);
       y3 = 320.0451719 - 0.2975215484 .* x2 + 6.351486e-4 .* x2.^2 - 8.14e-7 .* x2.^3;
       y4= 186645.2333 - 59.413863388 .* x2 + 0.476168398 .* x2.^2 - 5.08308e-4 .* x2.^3;
       plot(app.UIAxes2, x2, y3, 'b-', 'LineWidth', 2);  % azul
       hold(app.UIAxes2, 'on');
       plot(app.UIAxes2,Vf, AP, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');  % Punto rojo
       hold(app.UIAxes2, 'on');
       hold(app.UIAxes2, 'off');
       plot(app.UIAxes4, x2, y4, 'g-', 'LineWidth', 2); % verde punteado
       hold(app.UIAxes4, 'on');
       plot(app.UIAxes4,Vf, Pf, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');  % Punto rojo

       hold(app.UIAxes4, 'on');
       hold(app.UIAxes4, 'off');
       
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
              n=app.EditField.Value;
            
        end

        % Button pushed function: ModificarceldasButton
        function ModificarceldasButtonPushed(app, event)
            Qu=app.PotenciadecondensacinEditField.Value;
            Tamb=app.TemperaturaambienteEditField.Value;
            Tcond=app.TemperaturadecondensacinEditField.Value;
            Pu=app.PresinatmosfricaEditField.Value;
            n=app.EditField.Value;
            Naero=app.NmerodeceldasEditField.Value;
            run('AnadirN.mlx');
            app.NmerodeceldasEditField.Value=Nn;
            app.TemperaturadelairealasalidaEditField.Value=Tao;
            app.VelocidaddelventiladorEditField.Value=N;
            app.TemperaturaaguacondensadaEditField.Value=Tvo;
            app.RuidoEditField.Value=L;
            
            app.ConsumodelosventiladoresEditField.Value=Ptotal;
            app.RendiminetodelventiladorEditField.Value=Rendimiento;
            x2=linspace(-2000,2000);
       y3 = 320.0451719 - 0.2975215484 .* x2 + 6.351486e-4 .* x2.^2 - 8.14e-7 .* x2.^3;
       y4= 186645.2333 - 59.413863388 .* x2 + 0.476168398 .* x2.^2 - 5.08308e-4 .* x2.^3;
       plot(app.UIAxes2, x2, y3, 'b-', 'LineWidth', 2);  % azul
       hold(app.UIAxes2, 'on');
       plot(app.UIAxes2,Vf, AP, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');  % Punto rojo
       hold(app.UIAxes2, 'on');
       hold(app.UIAxes2, 'off');
       plot(app.UIAxes4, x2, y4, 'g-', 'LineWidth', 2); % verde punteado
       hold(app.UIAxes4, 'on');
       plot(app.UIAxes4,Vf, Pf, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');  % Punto rojo

       hold(app.UIAxes4, 'on');
       hold(app.UIAxes4, 'off');
        end

        % Button pushed function: DiseoeficienteButton
        function DiseoeficienteButtonPushed(app, event)
            Qu=app.PotenciadecondensacinEditField.Value;
            Tamb=app.TemperaturaambienteEditField.Value;
            Tcond=app.TemperaturadecondensacinEditField.Value;
            Pu=app.PresinatmosfricaEditField.Value;
            run('MaximizarR.mlx');
            app.NmerodeceldasEditField.Value=Naero;
            app.TemperaturadelairealasalidaEditField.Value=Tao;
            app.VelocidaddelventiladorEditField.Value=N;
            app.TemperaturaaguacondensadaEditField.Value=Tvo;
            app.RuidoEditField.Value=L;
            
            app.ConsumodelosventiladoresEditField.Value=Ptotal;
            app.RendiminetodelventiladorEditField.Value=Rendimiento;
            x2=linspace(-2000,2000);
       y3 = 320.0451719 - 0.2975215484 .* x2 + 6.351486e-4 .* x2.^2 - 8.14e-7 .* x2.^3;
       y4= 186645.2333 - 59.413863388 .* x2 + 0.476168398 .* x2.^2 - 5.08308e-4 .* x2.^3;
       plot(app.UIAxes2, x2, y3, 'b-', 'LineWidth', 2);  % azul
       hold(app.UIAxes2, 'on');
       plot(app.UIAxes2,Vf, AP, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');  % Punto rojo
       hold(app.UIAxes2, 'on');
       hold(app.UIAxes2, 'off');
       plot(app.UIAxes4, x2, y4, 'g-', 'LineWidth', 2); % verde punteado
       hold(app.UIAxes4, 'on');
       plot(app.UIAxes4,Vf, Pf, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');  % Punto rojo

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParmetrosdimensionalesdeloscomponentesTab = uitab(app.TabGroup);
            app.ParmetrosdimensionalesdeloscomponentesTab.Title = 'Parámetros dimensionales de los componentes';
            app.GridLayout = uigridlayout(app.ParmetrosdimensionalesdeloscomponentesTab);
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Potencia')
            xlabel(app.UIAxes3, 'Caudal')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Presión')
            xlabel(app.UIAxes, 'Caudal ')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ParmetrosdelostubosLabel = uilabel(app.GridLayout);
            app.ParmetrosdelostubosLabel.Text = 'Parámetros de los tubos';
            app.ProfundidaddetuboLabel = uilabel(app.GridLayout);
            app.ProfundidaddetuboLabel.Text = {'Profundidad '; 'de tubo'};
            app.ProfundidaddetuboEditField = uieditfield(app.GridLayout, 'numeric');
            app.ProfundidaddetuboEditField.ValueChangedFcn = createCallbackFcn(app, @ProfundidaddetuboEditFieldValueChanged, true);
            app.AnchuradetuboEditFieldLabel = uilabel(app.GridLayout);
            app.AnchuradetuboEditFieldLabel.Text = 'Anchura de tubo';
            app.AnchuradetuboEditField = uieditfield(app.GridLayout, 'numeric');
            app.AnchuradetuboEditField.ValueChangedFcn = createCallbackFcn(app, @AnchuradetuboEditFieldValueChanged, true);
            app.LongituddetubosEditFieldLabel = uilabel(app.GridLayout);
            app.LongituddetubosEditFieldLabel.Text = 'Longitud de tubos';
            app.LongituddetubosEditField = uieditfield(app.GridLayout, 'numeric');
            app.LongituddetubosEditField.ValueChangedFcn = createCallbackFcn(app, @LongituddetubosEditFieldValueChanged, true);
            app.ParmetrosdelventiladorLabel = uilabel(app.GridLayout);
            app.ParmetrosdelventiladorLabel.Text = 'Parámetros del ventilador';
            app.MostrardatosButton = uibutton(app.GridLayout, 'push');
            app.MostrardatosButton.ButtonPushedFcn = createCallbackFcn(app, @MostrardatosButtonPushed, true);
            app.MostrardatosButton.Text = 'Mostrar datos';
            app.DimetroEditFieldLabel = uilabel(app.GridLayout);
            app.DimetroEditFieldLabel.Text = 'Diámetro ';
            app.DimetroEditField = uieditfield(app.GridLayout, 'numeric');
            app.DimetroEditField.ValueChangedFcn = createCallbackFcn(app, @DimetroEditFieldValueChanged, true);
            app.mLabel = uilabel(app.GridLayout);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.GridLayout);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.GridLayout);
            app.mLabel_3.Text = 'm';
            app.mLabel_4 = uilabel(app.GridLayout);
            app.mLabel_4.Text = 'm';
            app.NmerodetubosEditFieldLabel = uilabel(app.GridLayout);
            app.NmerodetubosEditFieldLabel.Text = 'Número de tubos';
            app.NmerodetubosEditField = uieditfield(app.GridLayout, 'numeric');
            app.NmerodetubosEditField.ValueChangedFcn = createCallbackFcn(app, @NmerodetubosEditFieldValueChanged, true);
            app.CondicionesdeoperacinTab = uitab(app.TabGroup);
            app.CondicionesdeoperacinTab.Title = 'Condiciones de operación';
            app.GridLayout2 = uigridlayout(app.CondicionesdeoperacinTab);
            app.IntroduzcalossiguientesparmetrosLabel = uilabel(app.GridLayout2);
            app.IntroduzcalossiguientesparmetrosLabel.Text = 'Introduzca los siguientes parámetros';
            app.TemperaturadecondensacinEditFieldLabel = uilabel(app.GridLayout2);
            app.TemperaturadecondensacinEditFieldLabel.Text = 'Temperatura de condensación';
            app.TemperaturaambienteEditFieldLabel = uilabel(app.GridLayout2);
            app.TemperaturaambienteEditFieldLabel.Text = 'Temperatura ambiente';
            app.TemperaturadecondensacinEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TemperaturadecondensacinEditField.ValueChangedFcn = createCallbackFcn(app, @TemperaturadecondensacinEditFieldValueChanged, true);
            app.TemperaturaambienteEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TemperaturaambienteEditField.ValueChangedFcn = createCallbackFcn(app, @TemperaturaambienteEditFieldValueChanged, true);
            app.PresinatmosfricaEditFieldLabel = uilabel(app.GridLayout2);
            app.PresinatmosfricaEditFieldLabel.Text = 'Presión atmosférica';
            app.PresinatmosfricaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PresinatmosfricaEditField.ValueChangedFcn = createCallbackFcn(app, @PresinatmosfricaEditFieldValueChanged, true);
            app.MWLabel = uilabel(app.GridLayout2);
            app.MWLabel.Text = 'MW';
            app.KLabel_2 = uilabel(app.GridLayout2);
            app.KLabel_2.Text = 'K';
            app.KLabel_3 = uilabel(app.GridLayout2);
            app.KLabel_3.Text = 'K';
            app.TemperaturadelairealasalidaEditFieldLabel = uilabel(app.GridLayout2);
            app.TemperaturadelairealasalidaEditFieldLabel.Text = 'Temperatura del aire a la salida';
            app.NmerodeceldasEditFieldLabel = uilabel(app.GridLayout2);
            app.NmerodeceldasEditFieldLabel.Text = 'Número de celdas';
            app.VelocidaddelventiladorEditFieldLabel = uilabel(app.GridLayout2);
            app.VelocidaddelventiladorEditFieldLabel.Text = 'Velocidad del ventilador';
            app.barLabel = uilabel(app.GridLayout2);
            app.barLabel.Text = 'bar';
            app.CalcularButton = uibutton(app.GridLayout2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed2, true);
            app.CalcularButton.Text = 'Calcular';
            app.TemperaturadelairealasalidaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TemperaturadelairealasalidaEditField.ValueChangedFcn = createCallbackFcn(app, @TemperaturadelairealasalidaEditFieldValueChanged, true);
            app.NmerodeceldasEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NmerodeceldasEditField.ValueChangedFcn = createCallbackFcn(app, @NmerodeceldasEditFieldValueChanged, true);
            app.VelocidaddelventiladorEditField = uieditfield(app.GridLayout2, 'numeric');
            app.VelocidaddelventiladorEditField.ValueChangedFcn = createCallbackFcn(app, @VelocidaddelventiladorEditFieldValueChanged, true);
            app.KLabel_4 = uilabel(app.GridLayout2);
            app.KLabel_4.Text = 'K';
            app.rpmLabel = uilabel(app.GridLayout2);
            app.rpmLabel.Text = 'rpm';
            app.PotenciadecondensacinEditFieldLabel = uilabel(app.GridLayout2);
            app.PotenciadecondensacinEditFieldLabel.Text = 'Potencia de condensación';
            app.PotenciadecondensacinEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PotenciadecondensacinEditField.ValueChangedFcn = createCallbackFcn(app, @PotenciadecondensacinEditFieldValueChanged2, true);
            app.TemperaturaaguacondensadaEditFieldLabel = uilabel(app.GridLayout2);
            app.TemperaturaaguacondensadaEditFieldLabel.Text = 'Temperatura agua condensada';
            app.TemperaturaaguacondensadaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TemperaturaaguacondensadaEditField.ValueChangedFcn = createCallbackFcn(app, @TemperaturaaguacondensadaEditFieldValueChanged, true);
            app.KLabel_5 = uilabel(app.GridLayout2);
            app.KLabel_5.Text = 'K';
            app.ModificarceldasButton = uibutton(app.GridLayout2, 'push');
            app.ModificarceldasButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarceldasButtonPushed, true);
            app.ModificarceldasButton.Text = 'Modificar celdas';
            app.RuidoEditFieldLabel = uilabel(app.GridLayout2);
            app.RuidoEditFieldLabel.Text = 'Ruido';
            app.RuidoEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RuidoEditField.ValueChangedFcn = createCallbackFcn(app, @RuidoEditFieldValueChanged, true);
            app.DBLabel = uilabel(app.GridLayout2);
            app.DBLabel.Text = 'DB';
            app.EditField = uieditfield(app.GridLayout2, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.DiseoeficienteButton = uibutton(app.GridLayout2, 'push');
            app.DiseoeficienteButton.ButtonPushedFcn = createCallbackFcn(app, @DiseoeficienteButtonPushed, true);
            app.DiseoeficienteButton.Text = 'Diseño eficiente';
            app.ConsumosTab = uitab(app.TabGroup);
            app.ConsumosTab.Title = 'Consumos';
            app.GridLayout3 = uigridlayout(app.ConsumosTab);
            app.UIAxes4 = uiaxes(app.GridLayout3);
            title(app.UIAxes4, 'Potencia')
            xlabel(app.UIAxes4, 'Caudal')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'Presión')
            xlabel(app.UIAxes2, 'Caudal')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.ConsumodelosventiladoresEditFieldLabel = uilabel(app.GridLayout3);
            app.ConsumodelosventiladoresEditFieldLabel.Text = 'Consumo de los ventiladores';
            app.ConsumodelosventiladoresEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ConsumodelosventiladoresEditField.ValueChangedFcn = createCallbackFcn(app, @ConsumodelosventiladoresEditFieldValueChanged, true);
            app.RendiminetodelventiladorEditFieldLabel = uilabel(app.GridLayout3);
            app.RendiminetodelventiladorEditFieldLabel.Text = 'Rendimineto del ventilador';
            app.RendiminetodelventiladorEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RendiminetodelventiladorEditField.ValueChangedFcn = createCallbackFcn(app, @RendiminetodelventiladorEditFieldValueChanged, true);
            app.KWLabel = uilabel(app.GridLayout3);
            app.KWLabel.Text = 'KW';
            app.PuntodefuncionamientoLabel = uilabel(app.GridLayout3);
            app.PuntodefuncionamientoLabel.Text = 'Punto de funcionamiento';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AframeCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end